classdef President < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Button__13                    matlab.ui.control.StateButton
        Button__12                    matlab.ui.control.StateButton
        Button__11                    matlab.ui.control.StateButton
        Button__10                    matlab.ui.control.StateButton
        Button__9                     matlab.ui.control.StateButton
        Button__8                     matlab.ui.control.StateButton
        Button__7                     matlab.ui.control.StateButton
        Button__6                     matlab.ui.control.StateButton
        Button__5                     matlab.ui.control.StateButton
        Button__4                     matlab.ui.control.StateButton
        Button__3                     matlab.ui.control.StateButton
        Button__2                     matlab.ui.control.StateButton
        Button__1                     matlab.ui.control.StateButton
        Button_17                     matlab.ui.control.StateButton
        Button_16                     matlab.ui.control.StateButton
        Button_15                     matlab.ui.control.StateButton
        Button_14                     matlab.ui.control.StateButton
        Button_13                     matlab.ui.control.StateButton
        Button_12                     matlab.ui.control.StateButton
        Button_11                     matlab.ui.control.StateButton
        Button_10                     matlab.ui.control.StateButton
        Button_9                      matlab.ui.control.StateButton
        Button_8                      matlab.ui.control.StateButton
        Button_7                      matlab.ui.control.StateButton
        Button_6                      matlab.ui.control.StateButton
        Button_5                      matlab.ui.control.StateButton
        Button_4                      matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        Button_2                      matlab.ui.control.StateButton
        Button_1                      matlab.ui.control.StateButton
        ConfirmButton                 matlab.ui.control.Button
        HowManyPlayersEditField       matlab.ui.control.NumericEditField
        HowManyPlayersEditFieldLabel  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        JoinGameButton                matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        RulesButton                   matlab.ui.control.Button
        Player3Label                  matlab.ui.control.Label
        Player1Label                  matlab.ui.control.Label
        Player2Label                  matlab.ui.control.Label
        Player4Label                  matlab.ui.control.Label
        LastHandPlayedxLabel          matlab.ui.control.Label
        WhosUpPlLabel                 matlab.ui.control.Label
        PlayCardsButton               matlab.ui.control.Button
        SkipTurnButton                matlab.ui.control.Button
        Image6                        matlab.ui.control.Image
        Image2_3                      matlab.ui.control.Image
        Image2_5                      matlab.ui.control.Image
        Image2_4                      matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        cardNum             % Array of all 52 Cards
        cardImg             % Array of Card image files
        playerDecks         % MxN array with M = # of players & N = number of cards
        playedCards         % Cards on the table
        playedPosition      % Position in playerDecks of played cards
        selectedCards       % Cards selected to be played
        turnCount = 0       % Tracks how many turns have passed
        playerTurn = 1      % Which player's turn is it? Based on playerPriority loop, not actual player number

        numPlayers          % number of player
        gameState           % Is the round over?
        winPlayer = 0       % Who won the round
        playerWins          % Number of wins

        playedSetSize       % Size of played set of cards (double, triple, etc.)
        selectedSetSize     % Size of selected set of cards (double, triple, etc.)
        selectedCardValue   % Card numbers converted to values
        playedCardValue     % Card numbers converted to values

        buttonIconIndex      % Current State of card buttons

    end
    
    methods (Access = public)
        
        function imageArray = CallImage(~, cardArray)
            imageArray = cardArray + ".png";
        end

        function [] = UpdateIcons(app, inputDeck, buttonRange)
            imageButton = CallImage(app, inputDeck);
                for i = buttonRange
                    buttonName = "Button_" + i;
                    app.(buttonName).Icon = imageButton(i);
                end
        end
        function [] = UpdateIcons2(app, inputDeck, buttonRange)
            imageButton = CallImage(app, inputDeck);
                for i = buttonRange
                    buttonName = "Button__" + i;
                    app.(buttonName).Icon = imageButton(i);
                end
        end
        function [] = UpdateIconstest(app, inputDeck, buttonRange)
            imageButton = CallImage(app, inputDeck);
                for i = buttonRange
                    buttonName = "Button_" + i;
                    app.(buttonName).Icon = "back.png"
                end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.Image6.Visible = "off";
            app.Image2_5.Visible = "off";
            app.Image2_4.Visible = "off";
            app.Image2_3.Visible = "on";
            app.Image2_2.Visible = "off";
            app.Image2.Visible = "off";
            app.HomeButton.Visible = "off";
            app.HowManyPlayersEditField.Visible= "off";
            app.HowManyPlayersEditFieldLabel.Visible = "off";
            app.ConfirmButton.Visible ="off";
            app.Button_1.Visible="off";
            app.Button_2.Visible="off";
            app.Button_3.Visible="off";
            app.Button_4.Visible="off";
            app.Button_5.Visible="off";
            app.Button_6.Visible="off";
            app.Button_7.Visible="off";
            app.Button_8.Visible="off";
            app.Button_9.Visible="off";
            app.Button_10.Visible="off";
            app.Button_11.Visible="off";
            app.Button_12.Visible="off";
            app.Button_13.Visible="off";
            app.Button_14.Visible="off";
            app.Button_15.Visible="off";
            app.Button_17.Visible="off";
            app.Button_16.Visible="off";
            app.Button__1.Visible="off";
            app.Button__2.Visible="off";
            app.Button__3.Visible="off";
            app.Button__4.Visible="off";
            app.Button__5.Visible="off";
            app.Button__6.Visible="off";
            app.Button__7.Visible="off";
            app.Button__8.Visible="off";
            app.Button__9.Visible="off";
            app.Button__10.Visible="off";
            app.Button__11.Visible="off";
            app.Button__12.Visible="off";
            app.Button__13.Visible="off";

            
        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            RulesPopup
            Card_dealt = 'victory.mp3';
            audiowrite(Card_dealt,y,Fs);
            clear y Fs app
            [y,Fs] = audioread('victory.mp3');
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.Image6.Visible = "off";
            app.Image2_5.Visible = "off";
            app.Image2_4.Visible = "off";
            app.Image2_3.Visible = "on"; %% keep president image up 
            app.Image2_2.Visible = "off";
            app.Image2.Visible = "off";
            app.PlayButton.Visible = "off";
            app.JoinGameButton.Visible = "off";
            app.HowManyPlayersEditField.Visible ="on";
            app.HowManyPlayersEditFieldLabel.Visible = "on";
            app.ConfirmButton.Visible ="on";

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Game Initialization 
            app.numPlayers = app.HowManyPlayersEditField.Value;
            
            app.turnCount = 1;
            
            %How many players are there?
            if (app.numPlayers >= 2) && (app.numPlayers <= 4)

                %% if two players are selected
                if app.HowManyPlayersEditField.Value == 2
                    app.RulesButton.Visible = "on";
                    app.Player1Label.Visible = "on";
                    app.Player2Label.Visible = "on";
                    app.Player3Label.Visible = "off";
                    app.Player4Label.Visible = "off";
                    app.LastHandPlayedxLabel.Visible = "on";
                    app.WhosUpPlLabel.Visible = "on";
                    app.PlayCardsButton.Visible = "on";
                    app.SkipTurnButton.Visible = "on";
                    app.Image6.Visible = "on";
                    app.Image2_5.Visible = "off";
                    app.Image2_4.Visible = "on";
                    app.Image2_3.Visible = "on"; %% keep president image up 
                    app.Image2_2.Visible = "off";
                    app.Image2.Visible = "on";
                    app.PlayButton.Visible = "off";
                    app.JoinGameButton.Visible = "off";
                    app.HowManyPlayersEditField.Visible ="off";
                    app.HowManyPlayersEditFieldLabel.Visible = "off";
                    app.ConfirmButton.Visible ="off";
                    app.Button_1.Visible="on";
                    app.Button_2.Visible="on";
                    app.Button_3.Visible="on";
                    app.Button_4.Visible="on";
                    app.Button_5.Visible="on";
                    app.Button_6.Visible="on";
                    app.Button_7.Visible="on";
                    app.Button_8.Visible="on";
                    app.Button_9.Visible="on";
                    app.Button_10.Visible="on";
                    app.Button_11.Visible="on";
                    app.Button_12.Visible="on";
                    app.Button_13.Visible="on";
                    app.Button_14.Visible="on";
                    app.Button_15.Visible="on";
                    app.Button_16.Visible="on";
                    app.Button_17.Visible="on";
                    app.Button__1.Visible="on";
                    app.Button__2.Visible="on";
                    app.Button__3.Visible="on";
                    app.Button__4.Visible="on";
                    app.Button__5.Visible="on";
                    app.Button__6.Visible="on";
                    app.Button__7.Visible="on";
                    app.Button__8.Visible="on";
                    app.Button__9.Visible="on";
                    app.Button__10.Visible="on";
                    app.Button__11.Visible="on";
                    app.Button__12.Visible="on";
                    app.Button__13.Visible="on";

                end

                if app.HowManyPlayersEditField.Value == 3
                    app.RulesButton.Visible = "on";
                    app.Player1Label.Visible = "on";
                    app.Player2Label.Visible = "on";
                    app.Player3Label.Visible = "on";
                    app.Player4Label.Visible = "off";
                    app.LastHandPlayedxLabel.Visible = "on";
                    app.WhosUpPlLabel.Visible = "on";
                    app.PlayCardsButton.Visible = "on";
                    app.SkipTurnButton.Visible = "on";
                    app.Image6.Visible = "on";
                    app.Image2_5.Visible = "on";
                    app.Image2_4.Visible = "on";
                    app.Image2_3.Visible = "on"; %% keep president image up 
                    app.Image2_2.Visible = "off";
                    app.Image2.Visible = "on";
                    app.PlayButton.Visible = "off";
                    app.JoinGameButton.Visible = "off";
                    app.HowManyPlayersEditField.Visible ="off";
                    app.HowManyPlayersEditFieldLabel.Visible = "off";
                    app.ConfirmButton.Visible ="off";
                    app.Button_1.Visible="on";
                    app.Button_2.Visible="on";
                    app.Button_3.Visible="on";
                    app.Button_4.Visible="on";
                    app.Button_5.Visible="on";
                    app.Button_6.Visible="on";
                    app.Button_7.Visible="on";
                    app.Button_8.Visible="on";
                    app.Button_9.Visible="on";
                    app.Button_10.Visible="on";
                    app.Button_11.Visible="on";
                    app.Button_12.Visible="on";
                    app.Button_13.Visible="on";
                    app.Button_14.Visible="on";
                    app.Button_15.Visible="on";
                    app.Button_16.Visible="on";
                    app.Button_17.Visible="on";
                end

                %% if 4 players are selected
                if app.HowManyPlayersEditField.Value == 4
                    app.RulesButton.Visible = "on";
                    app.Player1Label.Visible = "on";
                    app.Player2Label.Visible = "on";
                    app.Player3Label.Visible = "on";
                    app.Player4Label.Visible = "on";
                    app.LastHandPlayedxLabel.Visible = "on";
                    app.WhosUpPlLabel.Visible = "on";
                    app.PlayCardsButton.Visible = "on";
                    app.SkipTurnButton.Visible = "on";
                    app.Image6.Visible = "on";
                    app.Image2_5.Visible = "on";
                    app.Image2_4.Visible = "on";
                    app.Image2_3.Visible = "on"; %% keep president image up 
                    app.Image2_2.Visible = "on";
                    app.Image2.Visible = "on";
                    app.PlayButton.Visible = "off";
                    app.JoinGameButton.Visible = "off";
                    app.HowManyPlayersEditField.Visible ="off";
                    app.HowManyPlayersEditFieldLabel.Visible = "off";
                    app.ConfirmButton.Visible ="off";
                    app.Button_1.Visible="on";
                    app.Button_2.Visible="on";
                    app.Button_3.Visible="on";
                    app.Button_4.Visible="on";
                    app.Button_5.Visible="on";
                    app.Button_6.Visible="on";
                    app.Button_7.Visible="on";
                    app.Button_8.Visible="on";
                    app.Button_9.Visible="on";
                    app.Button_10.Visible="on";
                    app.Button_11.Visible="on";
                    app.Button_12.Visible="on";
                    app.Button_13.Visible="on";
                    app.Button_14.Visible="on";
                    app.Button_15.Visible="on";
                    app.Button_16.Visible="on";
                    app.Button_17.Visible="on";
                end
                
                %Generate Card Decks
                app.cardNum = randperm(52);
                app.cardImg = app.cardNum + ".png";

                app.playerDecks = zeros(app.numPlayers, 13);
                for i = 1:app.numPlayers
                    app.playerDecks(i, :) = app.cardNum((13*i - 12): 13*i);%%row column
                    fprintf("A deck as been created! \n")
                    disp(app.playerDecks)
                end
                % for i = 1:app.numPlayers
                %     app.playerDecks(i, :) = app.cardNum((30*i - 12): 30*i);
                %     fprintf("A deck as been created! \n")
                %     disp(app.playerDecks)
                % end
                UpdateIcons(app, app.playerDecks(1,:), 1:13)
                UpdateIcons2(app, app.playerDecks(2,:), 1:13)

            else
                fprintf("Number of players not supported \n")
            end
            
        end

        % Button pushed function: PlayCardsButton
        function PlayCardsButtonPushed(app, event)
            
            %app.selectedCards = zeros(1, 13);
            app.selectedCards = [app.Button_1.Value, app.Button_2.Value, app.Button_3.Value, ...
                app.Button_4.Value, app.Button_5.Value, app.Button_6.Value, app.Button_7.Value, ...
                app.Button_8.Value, app.Button_9.Value, app.Button_10.Value, app.Button_11.Value, ...
                app.Button_12.Value, app.Button_13.Value];

            %disp(app.selectedCards)
            app.selectedCards = app.playerDecks(1,app.selectedCards);
            %disp(app.selectedCards)           
            
            app.playedSetSize = length(app.playedCards);
            app.playedCardValue = mod(app.playedCards, 13);

            app.selectedSetSize = length(app.selectedCards);
            app.selectedCardValue = mod(app.selectedCards, 13);
            
            if isempty(app.selectedCards)
                fprintf("Please select at least one card \n")
                return
            end

            %Check if cards played are of matching value
            if app.selectedSetSize > 1
                for i = 1:(app.selectedSetSize - 1)
                    if not(app.selectedCardValue(i) == app.selectedCardValue(i+1))
                        fprintf("Cards are not of matching value \n")
                        return
                    end
                end
            end
        
            %Check if first player
            if isempty(app.playedCards)
                app.playedCards = app.selectedCards;
                CallImage(app, app.playedCards)
                fprintf("Cards played sucessfully! \n")
                disp(app.playerDecks);
                for i = 1:length(app.playedCards)
                    app.playedPosition = find(app.playedCards(i) == app.playerDecks);
                    app.playerDecks(app.playedPosition) = 0;
                    disp(app.playerDecks);
                end
                return
        
            end
            
            %Check if number of cards played matches previous play
            if not(app.selectedSetSize == app.playedSetSize)
               fprintf("Does not match set size \n")
               return
            end
            
            %Check if cards played are higher value
            if app.selectedCardValue(1) > app.playedCardValue(1)
                app.playedCards = app.selectedCards;
                CallImage(app, app.playedCards);
                fprintf("Cards played sucessfully! \n")

                app.turnCount = app.turnCount + 1;
                app.playerTurn = mod(app.turnCount, app.numPlayers);
                    if app.playerTurn == 0
                        app.playerTurn = app.numPlayers;
                    end 

                    for i = 1:app.numPlayers
                    playersHand = app.playerDecks(i,:); %isolates one player's hand
                    summedHand = sum(playersHand); %sums all the values of that players hand

                    if summedHand == 0  %Checks if hand sums to 0, meaning the hand is empty
                        app.gameState = "Round Complete";
                        app.winPlayer = i;
                        break
                    else 
                        app.gameState = "Round Incomplete";
                        app.winPlayer = 0 ;
                    end 
                end 
            else
                fprintf("Value of cards not high enough \n")
            end
        end

        % Button pushed function: SkipTurnButton
        function SkipTurnButtonPushed(app, event)
switch app.Button_1.Value
    case true
        app.Button_1.Visible = "off"
end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.LastHandPlayedxLabel.Visible = "off";
            app.WhosUpPlLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.Image6.Visible = "off";
            app.Image2_5.Visible = "off";
            app.Image2_4.Visible = "off";
            app.Image2_3.Visible = "on";
            app.Image2_2.Visible = "off";
            app.Image2.Visible = "off";
            app.HomeButton.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'President Title.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background bigger.png');
            app.SkipTurnButton = uibutton(app.UIFigure, 'push');
            app.SkipTurnButton.ButtonPushedFcn = createCallbackFcn(app, @SkipTurnButtonPushed, true);
            app.SkipTurnButton.Text = 'Skip Turn';
            app.PlayCardsButton = uibutton(app.UIFigure, 'push');
            app.PlayCardsButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCardsButtonPushed, true);
            app.PlayCardsButton.Text = 'Play Cards';
            app.WhosUpPlLabel = uilabel(app.UIFigure);
            app.WhosUpPlLabel.Text = {'Who''s Up:'; 'Pl'};
            app.LastHandPlayedxLabel = uilabel(app.UIFigure);
            app.LastHandPlayedxLabel.Text = {'Last Hand Played:'; 'x'};
            app.Player4Label = uilabel(app.UIFigure);
            app.Player4Label.Text = 'Player 4';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.JoinGameButton = uibutton(app.UIFigure, 'push');
            app.JoinGameButton.Text = 'Join Game';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.HowManyPlayersEditFieldLabel = uilabel(app.UIFigure);
            app.HowManyPlayersEditFieldLabel.Text = 'How Many Players?';
            app.HowManyPlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'state');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'state');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'state');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'state');
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.UIFigure, 'state');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'state');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.UIFigure, 'state');
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.UIFigure, 'state');
            app.Button_17.Text = '';
            app.Button__1 = uibutton(app.UIFigure, 'state');
            app.Button__1.Text = '';
            app.Button__2 = uibutton(app.UIFigure, 'state');
            app.Button__2.Text = '';
            app.Button__3 = uibutton(app.UIFigure, 'state');
            app.Button__3.Text = '';
            app.Button__4 = uibutton(app.UIFigure, 'state');
            app.Button__4.Text = '';
            app.Button__5 = uibutton(app.UIFigure, 'state');
            app.Button__5.Text = '';
            app.Button__6 = uibutton(app.UIFigure, 'state');
            app.Button__6.Text = '';
            app.Button__7 = uibutton(app.UIFigure, 'state');
            app.Button__7.Text = '';
            app.Button__8 = uibutton(app.UIFigure, 'state');
            app.Button__8.Text = '';
            app.Button__9 = uibutton(app.UIFigure, 'state');
            app.Button__9.Text = '';
            app.Button__10 = uibutton(app.UIFigure, 'state');
            app.Button__10.Text = '';
            app.Button__11 = uibutton(app.UIFigure, 'state');
            app.Button__11.Text = '';
            app.Button__12 = uibutton(app.UIFigure, 'state');
            app.Button__12.Text = '';
            app.Button__13 = uibutton(app.UIFigure, 'state');
            app.Button__13.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = President

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end